classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        GridLayout    matlab.ui.container.GridLayout
        LeftPanel     matlab.ui.container.Panel
        TabGroup      matlab.ui.container.TabGroup
        Tab           matlab.ui.container.Tab
        Levelnet      matlab.ui.container.ButtonGroup
        connectlevel  matlab.ui.control.Button
        looplevel     matlab.ui.control.Button
        ButtonGroup   matlab.ui.container.ButtonGroup
        branch        matlab.ui.control.Button
        loop          matlab.ui.control.Button
        connect       matlab.ui.control.Button
        welcome       matlab.ui.control.TextArea
        Tab_2         matlab.ui.container.Tab
        project       matlab.ui.container.ButtonGroup
        theme         matlab.ui.control.Button
        polylevel     matlab.ui.control.Button
        parameter     matlab.ui.control.Button
        Volume        matlab.ui.control.Button
        welcome_2     matlab.ui.control.TextArea
        CenterPanel   matlab.ui.container.Panel
        UIAxes        matlab.ui.control.UIAxes
        RightPanel    matlab.ui.container.Panel
        TextArea      matlab.ui.control.TextArea
        file          matlab.ui.container.Menu
        txtin         matlab.ui.container.Menu
        excelin       matlab.ui.container.Menu
        savea         matlab.ui.container.Menu
        txtout        matlab.ui.container.Menu
        figout        matlab.ui.container.Menu
        about         matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = public)
        aboutapp%关于窗口
        traverseapp%导线窗口
        cellarraytext % 字符向量元胞数组
        filename
        path
        Angle %观测角
        d %观测距离
        knownnode%已知点
        nodename%点名
        direct%方位角
        direct_e%附和导线的末端已知方位角
        type%功能类型，1附和，2闭合
        atype%左右角,1左角，2右角
        xy%坐标成果
        aded = true;%是否已经平差
    end
    
    properties (Access = private)
    end
    
    methods (Access = private)
        
        function outPut(app,str)
            string = sprintf("%s\n",str);
            app.cellarraytext = horzcat(app.cellarraytext,string);
            app.TextArea.Value = app.cellarraytext;
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.cellarraytext{1} = sprintf("%s\n",'欢迎使用平差难！');
            app.TextArea.Value = app.cellarraytext{1};
            str = '平差难，难于上青天。';
            outPut(app,str);
            outPut(app,'你说人们的骨灰应该撒进海里。');
            [X,Y,Z] = peaks(25);
            CO(:,:,1) = zeros(25); % red
            CO(:,:,2) = ones(25).*linspace(0.5,0.6,25); % green
            CO(:,:,3) = ones(25).*linspace(0,1,25); % blue
            surf(app.UIAxes,X,Y,Z,CO)
        end

        % Menu selected function: excelin
        function excelinMenuSelected(app, event)
            [app.filename,app.path] = uigetfile({'*.xlsx';'*.xls'},'从excel文件导入', ...
                '.\data\');
            if app.filename ~= 0
                L = readcell(strcat(app.path,app.filename));
                try
                app.type = L{1,2};
                app.atype = L{1,4};
                if app.type == 2%闭合导线
                    app.knownnode = [L{3,2},L{3,3}];
                    app.nodename = L(8:length(L),1);
                    app.direct = cell2mat(L(5,1:3));
                    app.Angle = cell2mat(L(8:length(L),2:4));
                    app.d = cell2mat(L(8:length(L),5));
                    app.traverseapp = traverse(app);
                elseif app.type == 1%附和导线
                    app.knownnode = cell2mat(L(3:4,2:3));
                    app.nodename = L(10:length(L),1);
                    app.direct = cell2mat(L(6,1:3));
                    app.direct_e = cell2mat(L(7,1:3));
                    app.Angle = cell2mat(L(10:length(L),2:4));
                    app.d = cell2mat(L(10:length(L) - 1, 5));
                    app.traverseapp = traverse(app);
                end
                
                uiwait(msgbox('导入成功！请选择导线等级~','提示'));
                app.aded = false;
                catch
                uiwait(warndlg('数据类型不太对哦……','警告'));
                end
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            choice = questdlg("这就走了？QAQ","确认退出","是","否","否");
            switch choice
                case "是"
                    delete(app.traverseapp)
                    delete(app.aboutapp)
                    delete(app)
                    return;
                case "否"
                    return;
            end                        
        end

        % Menu selected function: about
        function aboutMenuSelected(app, event)
            app.about.Enable = 'off';
            app.aboutapp = about(app,app.UIFigure.Name,'1.0');    %#ok<*ADPROPLC> 
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {482, 482, 482};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {482, 482};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {272, '1x', 274};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '平差难2021';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '测量平差';
            app.welcome = uitextarea(app.Tab);
            app.welcome.Editable = 'off';
            app.welcome.Value = {'导入文件以开始：'};
            app.ButtonGroup = uibuttongroup(app.Tab);
            app.ButtonGroup.Title = '导线平差';
            app.connect = uibutton(app.ButtonGroup, 'push');
            app.connect.Text = '附和导线';
            app.loop = uibutton(app.ButtonGroup, 'push');
            app.loop.Text = '闭合导线';
            app.branch = uibutton(app.ButtonGroup, 'push');
            app.branch.Text = '支导线';
            app.Levelnet = uibuttongroup(app.Tab);
            app.Levelnet.Title = '水准路线平差';
            app.looplevel = uibutton(app.Levelnet, 'push');
            app.looplevel.Text = '闭合水准';
            app.connectlevel = uibutton(app.Levelnet, 'push');
            app.connectlevel.Text = '附和水准';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '工程计算';
            app.welcome_2 = uitextarea(app.Tab_2);
            app.welcome_2.Editable = 'off';
            app.welcome_2.Value = {'选择一项以开始：'};
            app.project = uibuttongroup(app.Tab_2);
            app.project.Title = '工程计算';
            app.Volume = uibutton(app.project, 'push');
            app.Volume.Text = '土方计算';
            app.parameter = uibutton(app.project, 'push');
            app.parameter.Text = '参数计算';
            app.polylevel = uibutton(app.project, 'push');
            app.polylevel.Text = '高程拟合';
            app.theme = uibutton(app.project, 'push');
            app.theme.Text = '主题解算';
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.RightPanel = uipanel(app.GridLayout);
            app.TextArea = uitextarea(app.RightPanel);
            app.TextArea.Editable = 'off';
            app.file = uimenu(app.UIFigure);
            app.file.Text = '文件';
            app.txtin = uimenu(app.file);
            app.txtin.Visible = 'off';
            app.txtin.Enable = 'off';
            app.txtin.Text = '从文本文件导入';
            app.excelin = uimenu(app.file);
            app.excelin.MenuSelectedFcn = createCallbackFcn(app, @excelinMenuSelected, true);
            app.excelin.Text = '从excel文件导入';
            app.savea = uimenu(app.file);
            app.savea.Visible = 'off';
            app.savea.Enable = 'off';
            app.savea.Text = '保存';
            app.txtout = uimenu(app.savea);
            app.txtout.Text = 'txt文件';
            app.figout = uimenu(app.savea);
            app.figout.Text = '误差图形';
            app.about = uimenu(app.UIFigure);
            app.about.MenuSelectedFcn = createCallbackFcn(app, @aboutMenuSelected, true);
            app.about.Text = '关于';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end