classdef traverse < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Menu         matlab.ui.container.Menu
        doadjust     matlab.ui.container.Menu
        typefield_2  matlab.ui.control.EditField
        Label_3      matlab.ui.control.Label
        Panel_7      matlab.ui.container.Panel
        UITable3_2   matlab.ui.control.Table
        Panel_6      matlab.ui.container.Panel
        TextArea_2   matlab.ui.control.TextArea
        UITable4_3   matlab.ui.control.Table
        Panel_5      matlab.ui.container.Panel
        UITable4_2   matlab.ui.control.Table
        Panel_4      matlab.ui.container.Panel
        UITable4     matlab.ui.control.Table
        Panel_3      matlab.ui.container.Panel
        UITable3     matlab.ui.control.Table
        typefield    matlab.ui.control.EditField
        Label_2      matlab.ui.control.Label
        Panel_2      matlab.ui.container.Panel
        TextArea     matlab.ui.control.TextArea
        UITable2     matlab.ui.control.Table
        Panel        matlab.ui.container.Panel
        UITable      matlab.ui.control.Table
        DropDown     matlab.ui.control.DropDown
        Label        matlab.ui.control.Label
        UIAxes       matlab.ui.control.UIAxes
    end


    properties (Access = private)
        Callingapp%接收主界面信号
        level=0%导线等级
        aoutlimit = false%角度超限
        doutlimit = false%坐标增量闭合差超限
        dlimit %全长相对闭合差限差
    end

    methods (Access = private)
        %%闭合导线，一已知点一方位角
        function dircAB(app,direct)%算方位角
            Angle10 = dms2degrees(app.Callingapp.Angle);
            direct10 = dms2degrees(direct);
            for i = 1:length(app.Callingapp.Angle)
                if app.Callingapp.atype == 1
                    Dir = direct10(i,:) + Angle10(i,:);
                elseif app.Callingapp.atype == 2
                    Dir = direct10(i,:) - Angle10(i,:);
                end
                if Dir > 180
                    Dir = Dir - 180;
                elseif Dir < 180
                    Dir = Dir + 180;
                end
                
                if length(direct) <= length(app.Callingapp.Angle)
                    direct10 = [direct10;Dir];
                end
            end
            direct = degrees2dms(direct10);
            app.Callingapp.direct = direct;
            D1 = array2table(app.Callingapp.direct);
            app.UITable4.Data = D1;
        end

        function results = angleplus(app, Angle)%角度闭合差
            Angle10 = dms2degrees(Angle);
            if app.Callingapp.type == 2
                angle_sum = sum(Angle10);
                
                results10 = -angle_sum + (length(Angle) - 2)*180;
                results = degrees2dms(results10);
            elseif app.Callingapp.type == 1
                dircAB(app,app.Callingapp.direct);
                results = -app.Callingapp.direct(end,:) + app.Callingapp.direct_e;
                if results(2)>0 && results(3) < 0
                    di = -results(3);
                    results(2) = results(2) - ceil(di/60);
                    results(3) = results(3) + ceil(di/60)*60;
                end

            end
        end
        function isoutlimit(app,level)%角度是否超限
            angleb = abs(angleplus(app,app.Callingapp.Angle));
            n = length(app.Callingapp.Angle);
            lim = 0;
            if level == 1
                lim = 10 * sqrt(n);
            elseif level == 2
                lim = 16 * sqrt(n);
            elseif level == 3
                lim = 24 * sqrt(n);
            elseif level == 4
                lim = 60 * sqrt(n);
            end
            if lim ~= 0
                if angleb(2)*60 + angleb(3) <= lim
                    app.TextArea.Value = sprintf...
                        ('未超限，闭合差%2.1f\"<=%2.2f\"', ...
                        angleb(2)*60 + angleb(3), ...
                        lim);
                    app.aoutlimit = false;
                    try
                        if app.Callingapp.type == 2
                            angle2(app,app.Callingapp.Angle);
                            dircAB(app,app.Callingapp.direct);
                            dimxy(app,app.Callingapp.d, ...
                                app.Callingapp.knownnode, ...
                                app.Callingapp.direct);
                            app.Menu.Enable = 'off';

                        elseif app.Callingapp.type == 1
                            angle2(app,app.Callingapp.Angle);
                            app.Callingapp.direct = app.Callingapp.direct(1,:);
                            dircAB(app,app.Callingapp.direct);
                            dimxy(app,app.Callingapp.d,app.Callingapp.knownnode, app.Callingapp.direct);
                        end
                    catch
                        warning();
                        uiwait(warndlg('TAT，这份数据太难了，换一份可以吗……','警告'));
                    end
                else
                    app.TextArea.FontColor = 'r';
                    app.TextArea.Value = sprintf...
                        ('已超限，闭合差%2.1f\">%2.2f\"，请检查数据！' ...
                        ,angleb(2)*60 + angleb(3),lim);
                    app.doutlimit = true;
                    app.Menu.Enable = 'off';
                end
            end

        end

        function angle2(app,Angle)%角度改正
            dangle = angleplus(app,app.Callingapp.Angle);
            dangle10 = dms2degrees(dangle);
            Angle10 = dms2degrees(Angle);
            if app.Callingapp.type == 2
                dangle = round(dangle/length(Angle));
                dangle10 = dms2degrees(dangle);
                Angle10 = Angle10 + dangle10;
                dd = sum(Angle10) - (length(Angle10) - 2) * 180;
               
            elseif app.Callingapp.type == 1
                Dangle = dms2degrees(dangle);
                Dangle = round(Dangle/length(Angle));
                Angle10 = Angle10 - Dangle;
                da = Dangle*length(Angle);
                dd = da - (dangle10);                
            end
            if dd ~= 0
                if app.Callingapp.type == 2
                    Angle10(end) = Angle10(end) - dd;
                else
                    Angle10(end) = Angle10(end) + dd;
                end
            end            
            app.Callingapp.Angle = degrees2dms(Angle10);
            D = array2table(app.Callingapp.Angle);
            app.UITable4_2.Data = D;
        end

        function [results,re] = dimxy(app,d,knownnode,direct)%xy方向坐标增量闭合差,
            % xy方向坐标增量
            d1 = dms2degrees(direct);
            re = zeros(length(d),2);
            re(1,:) = knownnode(1,:);

            if app.Callingapp.type == 1
                for i = 1:length(d)
                    dx = d(i) * cosd(d1(i+1));
                    dy = d(i) * sind(d1(i+1));
                    re(i,:) = [dx,dy];
                end
                results = sum(re)+[app.Callingapp.knownnode(1,1)-app.Callingapp.knownnode(2,1),app.Callingapp.knownnode(1,2)-app.Callingapp.knownnode(2,2)];
            else
                for i = 1:length(d)
                    dx = d(i) * cosd(d1(i));
                    dy = d(i) * sind(d1(i));
                    re(i,:) = [dx,dy];
                end
                results = sum(re);
            end
            D1 = array2table(re);
            app.UITable4_3.Data = D1;
            D2 = sqrt(results(1).^2+results(2).^2);
            D = sum(app.Callingapp.d) ./ D2;
            if D > app.dlimit
                app.TextArea_2.Value = sprintf( ...
                    "未超限，导线全长相对闭合差：1/%.2f<1/%d",D,app.dlimit);
                app.doutlimit = false;
                app.Menu.Enable = 'off';
                app.doadjust.Enable = 'on';
            else
                app.TextArea_2.FontColor = 'r';
                app.TextArea_2.Value = sprintf( ...
                    "超限，导线全长相对闭合差：1/%.2f>=1/%d,请检查数据！" ...
                    ,D,app.dlimit);
                app.doutlimit = true;
            end
        end

        function ddxy = xy2(app,d,knownnode)%坐标改正
            [dxy,ddxy] = dimxy(app,app.Callingapp.d,app.Callingapp.knownnode,app.Callingapp.direct);
            sumd = sum(d);
            Dxy = dxy.*d./sumd;
            ddxy = ddxy-Dxy;%改正后坐标增量
            app.Callingapp.xy = zeros(length(d),2);
            app.Callingapp.xy(1,:) = knownnode(1,:) + ddxy(1,:);
            for i = 2:length(app.Callingapp.d)
                app.Callingapp.xy(i,:) = app.Callingapp.xy(i-1,:)+ddxy(i,:);
            end
            sum(ddxy);
            D1 = array2table(ddxy);
            app.UITable4_3.Data = D1;
            if app.Callingapp.type == 1
                D = array2table([app.Callingapp.knownnode(1,:);app.Callingapp.xy],'VariableNames',{'x','y'});
                D0 = cell2table(app.Callingapp.nodename,"VariableNames",{'NAME'});
                D = [D0,D];
            elseif app.Callingapp.type == 2
                D = array2table(app.Callingapp.xy,'VariableNames',{'x','y'});
                D0 = cell2table(app.Callingapp.nodename,"VariableNames",{'NAME'});
                D = [D0,D];
            end
            app.UITable3_2.Data = D;
        end


        function Adjust(app,~,~,~,~,~,~)%闭合平差计算

            xy2(app,app.Callingapp.d,app.Callingapp.knownnode);
            app.Callingapp.xy = [app.Callingapp.knownnode(1,:);app.Callingapp.xy];
            app.Callingapp.aded = true;
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main)
            app.Callingapp = main;
            angle = array2table(app.Callingapp.Angle);
            app.UITable.Data = angle;
            angleb = app.angleplus(app.Callingapp.Angle);
            anglea = array2table(angleb);
            app.UITable2.Data = anglea;
            if app.Callingapp.atype ==1
                app.typefield_2.Value = '左角';
            elseif app.Callingapp.atype == 2
                app.typefield_2.Value = '右角';
            end
            if app.Callingapp.type == 1
                app.typefield.Value = '附和导线';
            elseif app.Callingapp.type == 2
                app.typefield.Value = '闭合导线';
            elseif app.Callingapp.type == 3
                app.typefield.Value = '支导线';
            end
            kn = array2table(app.Callingapp.knownnode);
            app.UITable3.Data = kn;
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            switch str2double(value)
                case 1
                    app.dlimit = 10000;

                case 2
                    app.dlimit = 7000;
                case 3
                    app.dlimit = 5000;
                case 4
                    app.dlimit = 2000;
                otherwise
                    app.dlimit = 0;
            end
            app.Menu.Enable = 'on';
            app.level = str2double(value);
        end

        % Menu selected function: Menu
        function MenuSelected(app, event)
            isoutlimit(app,app.level);
        end

        % Menu selected function: doadjust
        function doadjustSelected(app, event)
            if ~app.aoutlimit
                if ~app.doutlimit
                    app.Adjust(app);
                end
                try
                    uiwait(msgbox('平差成功~'));
                    app.doadjust.Enable = 'off';
                    app.Callingapp.savea.Visible = 'on';
                    app.Callingapp.savea.Enable = 'on';
                    plot(app.UIAxes,app.Callingapp.xy(:,2), ...
                        app.Callingapp.xy(:,1),'k.-','MarkerSize',12);
                    if app.Callingapp.type == 2
                        text(app.UIAxes,app.Callingapp.xy(:,2)-10, ...
                            app.Callingapp.xy(:,1)-10, ...
                            [app.Callingapp.nodename(end);app.Callingapp.nodename(:)], ...
                            'FontSize',14);
                    elseif app.Callingapp.type == 1
                        text(app.UIAxes,app.Callingapp.xy(:,2)-10, ...
                            app.Callingapp.xy(:,1)-10, ...
                            app.Callingapp.nodename(:), ...
                            'FontSize',14);
                    end
                    hold(app.UIAxes,"on");
                    plot(app.UIAxes,app.Callingapp.knownnode(1,2), ...
                        app.Callingapp.knownnode(1,1),'^','MarkerSize',13, ...
                        'MarkerFaceColor','r');
                    legend(app.UIAxes,'导线','已知点1','Location',"best");
                    legend(app.UIAxes,'boxoff');
                    if app.Callingapp.type == 1
                        hold(app.UIAxes,"on");
                        plot(app.UIAxes,app.Callingapp.knownnode(2,2), ...
                            app.Callingapp.knownnode(2,1),'^', ...
                            'MarkerSize',13,'MarkerFaceColor','r');
                        legend(app.UIAxes,'导线','已知点1','已知点2','Location',"best");
                        legend(app.UIAxes,'boxoff');
                    end
                catch
                    uiwait(warndlg('啊哦，数据出问题了……','错误'));
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '导线平差';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.MenuSelectedFcn = createCallbackFcn(app, @MenuSelected, true);
            app.Menu.Enable = 'off';
            app.Menu.Text = '闭合差检查';
            app.doadjust = uimenu(app.UIFigure);
            app.doadjust.MenuSelectedFcn = createCallbackFcn(app, @doadjustSelected, true);
            app.doadjust.Enable = 'off';
            app.doadjust.Text = '开始平差';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '平差成果图')
            xlabel(app.UIAxes, 'Y')
            ylabel(app.UIAxes, 'X')
            zlabel(app.UIAxes, 'Z')
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '导线等级';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'', '一等', '二等', '三等', '图根'};
            app.DropDown.ItemsData = {'0', '1', '2', '3', '4'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '0';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '角度观测值';
            app.UITable = uitable(app.Panel);
            app.UITable.ColumnName = {'°'; ''''; ''''''};
            app.UITable.RowName = {};
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = '角度闭合差';
            app.UITable2 = uitable(app.Panel_2);
            app.UITable2.ColumnName = {'°'; ''''; ''''''};
            app.UITable2.RowName = {};
            app.TextArea = uitextarea(app.Panel_2);
            app.TextArea.Editable = 'off';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '导线类型';
            app.typefield = uieditfield(app.UIFigure, 'text');
            app.typefield.Editable = 'off';
            app.Panel_3 = uipanel(app.UIFigure);
            app.Panel_3.Title = '已知点';
            app.UITable3 = uitable(app.Panel_3);
            app.UITable3.ColumnName = {'x'; 'y'};
            app.UITable3.RowName = {};
            app.Panel_4 = uipanel(app.UIFigure);
            app.Panel_4.Title = '方位角';
            app.UITable4 = uitable(app.Panel_4);
            app.UITable4.ColumnName = {'°'; ''''; '"'};
            app.UITable4.RowName = {};
            app.Panel_5 = uipanel(app.UIFigure);
            app.Panel_5.Title = '改正后角度';
            app.UITable4_2 = uitable(app.Panel_5);
            app.UITable4_2.ColumnName = {'°'; ''''; '"'};
            app.UITable4_2.RowName = {};
            app.Panel_6 = uipanel(app.UIFigure);
            app.Panel_6.Title = '坐标增量及导线全长相对闭合差';
            app.UITable4_3 = uitable(app.Panel_6);
            app.UITable4_3.ColumnName = {'dx'; 'dy'};
            app.UITable4_3.RowName = {};
            app.TextArea_2 = uitextarea(app.Panel_6);
            app.TextArea_2.Editable = 'off';
            app.Panel_7 = uipanel(app.UIFigure);
            app.Panel_7.Title = '平差结果';
            app.UITable3_2 = uitable(app.Panel_7);
            app.UITable3_2.ColumnName = {'NAME'; 'x'; 'y'};
            app.UITable3_2.RowName = {};
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '测角类型';
            app.typefield_2 = uieditfield(app.UIFigure, 'text');
            app.typefield_2.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = traverse(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end